classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        r_2mmEditField       matlab.ui.control.NumericEditField
        r_2mmEditFieldLabel  matlab.ui.control.Label
        r_1mmEditField       matlab.ui.control.NumericEditField
        r_1mmEditFieldLabel  matlab.ui.control.Label
        UkVEditField_2       matlab.ui.control.NumericEditField
        UkVEditField_2Label  matlab.ui.control.Label
        Button               matlab.ui.control.Button
        pMPaDropDown         matlab.ui.control.DropDown
        pMPaDropDownLabel    matlab.ui.control.Label
        dmmDropDown          matlab.ui.control.DropDown
        dmmDropDownLabel     matlab.ui.control.Label
        U_bkVEditField       matlab.ui.control.NumericEditField
        U_bkVEditFieldLabel  matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CalculateObject
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            app.CalculateObject.d7 = str2double(app.dmmDropDown.Value);
            app.CalculateObject.p = str2double(app.pMPaDropDown.Value);
            app.CalculateObject.voltage = app.UkVEditField_2.Value;
            app.CalculateObject.radius1 = app.r_1mmEditField.Value;
            app.CalculateObject.radius2 = app.r_2mmEditField.Value;

            [U, eta] = method1(app.CalculateObject);

            if (eta(app.CalculateObject.d7) > 0)
                app.U_bkVEditField.Value = U(app.CalculateObject.d7, app.CalculateObject.p * 100 / 5) / 1000;
            else
                app.U_bkVEditField.Value = -1;
            end
            
            logical = nonzeros(eta > 0);
            
            plot(app.UIAxes, (1 : size(logical, 1)), U(1 : size(logical, 1), :) / 1000)
            xlim(app.UIAxes, [1 100])
            ylim(app.UIAxes, [0 100])
            p1 = 0.05 : 0.05 : 0.3;
            legend(app.UIAxes, string(p1) + ' MPa')
            xlabel(app.UIAxes, 'd / mm')
            ylabel(app.UIAxes, 'U_{b} / kV')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.CalculateObject.d7, app.U_bkVEditField.Value, 'o', 'MarkerFaceColor', 'b')
            hold(app.UIAxes, 'off')

            plot(app.UIAxes2, (1 : 100), eta)
            xlim(app.UIAxes2, [1 100])
            xlabel(app.UIAxes2, 'd / mm')
            ylabel(app.UIAxes2, 'eta')

%             figure
%             plot( U(1 : size(logical, 1), 2) / 1000)
%             xlim([1 100])
%             ylim([0 100])
%             xlabel('d / mm')
%             ylabel('U_{b} / kV')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CalculateObject = Calculate;
            updatePlot(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            updatePlot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {431, 431};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {218, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.U_bkVEditFieldLabel = uilabel(app.LeftPanel);
            app.U_bkVEditFieldLabel.Text = 'U_b / kV';
            app.U_bkVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dmmDropDownLabel = uilabel(app.LeftPanel);
            app.dmmDropDownLabel.Text = 'd / mm';
            app.dmmDropDown = uidropdown(app.LeftPanel);
            app.dmmDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '83', '84', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94', '95', '96', '97', '98', '99', '100'};
            app.dmmDropDown.Value = '30';
            app.pMPaDropDownLabel = uilabel(app.LeftPanel);
            app.pMPaDropDownLabel.Text = 'p / MPa';
            app.pMPaDropDown = uidropdown(app.LeftPanel);
            app.pMPaDropDown.Items = {'0.05', '0.1', '0.15', '0.2', '0.25', '0.3'};
            app.pMPaDropDown.Value = '0.05';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '=';
            app.UkVEditField_2Label = uilabel(app.LeftPanel);
            app.UkVEditField_2Label.Text = 'U / kV';
            app.UkVEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.UkVEditField_2.Value = 50;
            app.r_1mmEditFieldLabel = uilabel(app.LeftPanel);
            app.r_1mmEditFieldLabel.Text = 'r_1 / mm';
            app.r_1mmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.r_1mmEditField.Value = 12;
            app.r_2mmEditFieldLabel = uilabel(app.LeftPanel);
            app.r_2mmEditFieldLabel.Text = 'r_2 / mm';
            app.r_2mmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.r_2mmEditField.Value = 60;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Breakdown voltage of synthetic air between two spheres')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Ratio of the magnitudes of mean and maximum field')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end